classdef FinalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SistemadedeteccindeplacasLabel  matlab.ui.control.Label
        SNoLabel                        matlab.ui.control.Label
        EsperuanaLabel                  matlab.ui.control.Label
        SegmentacindecaracteresPerLabel  matlab.ui.control.Label
        SegmentacindecaracteresPlacaLabel  matlab.ui.control.Label
        SegmentacinhorizontalLabel      matlab.ui.control.Label
        DeteccinplacaLabel              matlab.ui.control.Label
        ImagenoriginalLabel             matlab.ui.control.Label
        NmerodeplacaLabel               matlab.ui.control.Label
        PlacaLabel                      matlab.ui.control.Label
        PredefinedImagesLabel           matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        LoadButton                      matlab.ui.control.Button
        ImageAxes_5                     matlab.ui.control.UIAxes
        ImageAxes_4                     matlab.ui.control.UIAxes
        ImageAxes_3                     matlab.ui.control.UIAxes
        ImageAxes_2                     matlab.ui.control.UIAxes
        ImageAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        numeroPlaca % Description
   
    end
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            if strcmp(imagefile,'Placa 1')
                im = imread('placa1.jpg', 3);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);   
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
             
        end

        function identificarPlaca(app,imagefile)
            [app.numeroPlaca,es_peruana]=AutomaticPeruvianPlateRecognition(imagefile);
            app.NmerodeplacaLabel.Text = app.numeroPlaca;
            
            placa=imread('AppFiles/placa.jpg');
            imagesc(app.ImageAxes_2,placa);

            segmentacion_caracteres=imread('AppFiles/caracteres.jpg');
            imagesc(app.ImageAxes_4,segmentacion_caracteres);

            segmentacion_lineas=imread('AppFiles/lineas.jpg');
            imagesc(app.ImageAxes_3,segmentacion_lineas);

            segmentacion_lineas=imread('AppFiles/pais.jpg');
            imagesc(app.ImageAxes_5,segmentacion_lineas);

            if(es_peruana)
                app.SNoLabel.Text='Sí';
            else
                app.SNoLabel.Text='No';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            updateimage(app, 'placa1.jpg');
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.NmerodeplacaLabel.Text='Número de placa';
            app.SNoLabel.Text='Sí / No';
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);

             
            inicial=char(app.DropDown.Value);
            
            identificarPlaca(app,app.DropDown.Value) %Identificar la placa
            

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
               inicial=char(app.DropDown.Value);
                if(inicial(1)=='p')
                    identificarPlaca(app,fname) %Identificar la placa
                else
                    identificarPlacaLejos(app,fname) %Identificar la placa
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.ImageAxes_2 = uiaxes(app.UIFigure);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.XTickLabel = {'[ ]'};
            app.ImageAxes_2.YTick = [];
            app.ImageAxes_3 = uiaxes(app.UIFigure);
            app.ImageAxes_3.XTick = [];
            app.ImageAxes_3.XTickLabel = {'[ ]'};
            app.ImageAxes_3.YTick = [];
            app.ImageAxes_4 = uiaxes(app.UIFigure);
            app.ImageAxes_4.XTick = [];
            app.ImageAxes_4.XTickLabel = {'[ ]'};
            app.ImageAxes_4.YTick = [];
            app.ImageAxes_5 = uiaxes(app.UIFigure);
            app.ImageAxes_5.XTick = [];
            app.ImageAxes_5.XTickLabel = {'[ ]'};
            app.ImageAxes_5.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Cargar imagen';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Placa 1', 'Placa 2', 'Placa 3', 'Placa 4', 'Placa 5', 'Auto 1', 'Auto 2', 'Auto 3', 'Auto 4', 'Auto 5'};
            app.DropDown.ItemsData = {'placa1.jpg', 'placa2.jpg', 'placa3.jpg', 'placa4.jpg', 'placa5.jpg', 'auto1.jpg', 'auto2.jpg', 'auto3.jpg', 'auto4.jpg', 'auto5.jpg'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'placa1.jpg';
            app.PredefinedImagesLabel = uilabel(app.UIFigure);
            app.PredefinedImagesLabel.Text = 'Imagenes predefinidas';
            app.PlacaLabel = uilabel(app.UIFigure);
            app.PlacaLabel.Text = 'Placa: ';
            app.NmerodeplacaLabel = uilabel(app.UIFigure);
            app.NmerodeplacaLabel.Text = 'Número de placa';
            app.ImagenoriginalLabel = uilabel(app.UIFigure);
            app.ImagenoriginalLabel.Text = 'Imagen original';
            app.DeteccinplacaLabel = uilabel(app.UIFigure);
            app.DeteccinplacaLabel.Text = 'Detección placa';
            app.SegmentacinhorizontalLabel = uilabel(app.UIFigure);
            app.SegmentacinhorizontalLabel.Text = 'Segmentación horizontal';
            app.SegmentacindecaracteresPlacaLabel = uilabel(app.UIFigure);
            app.SegmentacindecaracteresPlacaLabel.Text = 'Segmentación de caracteres - Placa';
            app.SegmentacindecaracteresPerLabel = uilabel(app.UIFigure);
            app.SegmentacindecaracteresPerLabel.Text = 'Segmentación de caracteres - Perú';
            app.EsperuanaLabel = uilabel(app.UIFigure);
            app.EsperuanaLabel.Text = 'Es peruana:';
            app.SNoLabel = uilabel(app.UIFigure);
            app.SNoLabel.Text = 'Sí / No';
            app.SistemadedeteccindeplacasLabel = uilabel(app.UIFigure);
            app.SistemadedeteccindeplacasLabel.Text = 'Sistema de detección de placas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end